classdef EEG_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        DataTab                         matlab.ui.container.Tab
        SignaloptionsandartefactsPanel  matlab.ui.container.Panel
        HzLabel                         matlab.ui.control.Label
        sLabel                          matlab.ui.control.Label
        dBLabel                         matlab.ui.control.Label
        sLabel_2                        matlab.ui.control.Label
        MovementDropDownLabel           matlab.ui.control.Label
        MovementDropDown                matlab.ui.control.DropDown
        FsEditFieldLabel                matlab.ui.control.Label
        FsEditField                     matlab.ui.control.NumericEditField
        DurationEditFieldLabel          matlab.ui.control.Label
        DurationEditField               matlab.ui.control.NumericEditField
        SNREditFieldLabel               matlab.ui.control.Label
        SNREditField                    matlab.ui.control.NumericEditField
        EyeblinkCheckBox                matlab.ui.control.CheckBox
        EMGeffectsCheckBox              matlab.ui.control.CheckBox
        EOGeffectsCheckBox              matlab.ui.control.CheckBox
        PowerlinesCheckBox              matlab.ui.control.CheckBox
        GeneratedataButton              matlab.ui.control.Button
        FilePanel                       matlab.ui.container.Panel
        FilenameEditFieldLabel          matlab.ui.control.Label
        FilenameEditField               matlab.ui.control.EditField
        LoaddataButton                  matlab.ui.control.Button
        MovementStartingTimeLabel       matlab.ui.control.Label
        MovementStartingTimeEditField   matlab.ui.control.NumericEditField
        UIAxes                          matlab.ui.control.UIAxes
        PreprocessingTab                matlab.ui.container.Tab
        BPFilterCheckBox                matlab.ui.control.CheckBox
        NotchFilterCheckBox             matlab.ui.control.CheckBox
        BPFilterparametersPanel         matlab.ui.container.Panel
        TypeDropDownLabel               matlab.ui.control.Label
        TypeDropDown                    matlab.ui.control.DropDown
        OrderEditFieldLabel             matlab.ui.control.Label
        OrderEditField                  matlab.ui.control.NumericEditField
        LFreqEditFieldLabel             matlab.ui.control.Label
        LFreqEditField                  matlab.ui.control.NumericEditField
        HFreqEditFieldLabel             matlab.ui.control.Label
        HFreqEditField                  matlab.ui.control.NumericEditField
        WindowTypeLabel                 matlab.ui.control.Label
        WindowTypeDropDown              matlab.ui.control.DropDown
        HzLabel_2                       matlab.ui.control.Label
        HzLabel_3                       matlab.ui.control.Label
        ApplytemporalfilteringButton    matlab.ui.control.Button
        TemporalFilteringLabel          matlab.ui.control.Label
        SpatialFilteringLabel           matlab.ui.control.Label
        FilteringMethodDropDownLabel    matlab.ui.control.Label
        FilteringMethodDropDown         matlab.ui.control.DropDown
        SpatialFilterparametersPanel    matlab.ui.container.Panel
        ApplyspatialfilteringButton     matlab.ui.control.Button
        NotchFilterparametersPanel      matlab.ui.container.Panel
        FreqEditFieldLabel              matlab.ui.control.Label
        FreqEditField                   matlab.ui.control.NumericEditField
        HzLabel_6                       matlab.ui.control.Label
        ExtractionTab                   matlab.ui.container.Tab
        ExtractionMethodDropDownLabel   matlab.ui.control.Label
        ExtractionMethodDropDown        matlab.ui.control.DropDown
        ClassificiationTab              matlab.ui.container.Tab
        ClassificationMethodDropDownLabel  matlab.ui.control.Label
        ClassificationMethodDropDown    matlab.ui.control.DropDown
        AnalysisTab                     matlab.ui.container.Tab
        ResultsTab                      matlab.ui.container.Tab
    end

    methods (Access = private)

        % Button pushed function: GeneratedataButton
        function Generate(app, event)
            close all;
            Fs=app.FsEditField.Value;
            assignin('base', 'Fs', Fs)
            SNR=app.SNREditField.Value;
            assignin('base', 'SNR', SNR)
            time_movement=app.MovementStartingTimeEditField.Value;
            assignin('base', 'time_movement', time_movement)
            t_end=app.DurationEditField.Value;
            assignin('base', 't_end', t_end)
            eye_blink_check=int8(app.EyeblinkCheckBox.Value);
            assignin('base', 'eye_blink_check', eye_blink_check)
            power_line_check=int8(app.PowerlinesCheckBox.Value);
            assignin('base', 'power_line_check', power_line_check)
            myMove=app.MovementDropDown.Value;
            assignin('base', 'myMove', myMove)
            if strcmp(myMove,'Right hand')
                movement=1;
            elseif strcmp(myMove,'Left hand')
                movement=2;
            else
                movement=3;
            end
            assignin('base', 'movement', movement)
            [C3, C4, Cz]=eeg_generator(Fs,t_end,SNR,movement, time_movement,eye_blink_check,power_line_check);
            channels=[C3;C4;Cz];
            time=linspace(0,t_end,Fs*t_end+1);
            assignin('base', 'rawData',channels)
            
            plot(app.UIAxes,time,C3)
            %% Save the signals and parameters
            save('channels.mat','C3','C4','Cz') 
            parameters=[];
            parameters.duration=duration;
            parameters.fsample=Fs;
            parameters.timestart=time_movement;
            parameters.movement=movement;
            parameters.eye_blink_check=eye_blink_check;
            parameters.power_line_check=power_line_check;
            save('parameters.mat', 'parameters')
            %a=evalin('base','a'); % To load a variable from the workspace
            
        end

        % Button pushed function: LoaddataButton
        function Load(app, event)
            filename=app.FilenameEditField.Value
            file=load(filename)
            assignin('base', 'myData', file)
            
        end

        % Callback function
        function ValueEditFieldValueChanged(app, event)
            
        end

        % Button pushed function: ApplyspatialfilteringButton
        function spatial_filtering(app, event)
            
        end

        % Button pushed function: ApplytemporalfilteringButton
        function temporal_filtering(app, event)
            %% Get the values from the app
            BPFilter=int8(app.BPFilterCheckBox.Value);
            NotchFilter=int8(app.NotchFilterCheckBox.Value);
            LFreq=app.LFreqEditField.Value;
            HFreq=app.HFreqEditField.Value;
            FreqBand=[LFreq HFreq];
            Type=app.TypeDropDown.Value;
            N=app.OrderEditField.Value;
            assignin('base', 'Type', Type)
            rawData=evalin('base','rawData'); 
            Fs=evalin('base','Fs');
            %[filtered_data] = ft_preproc_bandpassfilter(rawData, Fs, FreqBand, N, Type, 'twopass')
            %[filtered_data] = filter1('bp',rawData,'fc',[LFreq HFreq],'fs',Fs); 
            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.DataTab = uitab(app.TabGroup);
            app.DataTab.Title = 'Data';
            app.SignaloptionsandartefactsPanel = uipanel(app.DataTab);
            app.SignaloptionsandartefactsPanel.Title = 'Signal options and artefacts';
            app.HzLabel = uilabel(app.SignaloptionsandartefactsPanel);
            app.HzLabel.Text = 'Hz';
            app.sLabel = uilabel(app.SignaloptionsandartefactsPanel);
            app.sLabel.Text = 's';
            app.dBLabel = uilabel(app.SignaloptionsandartefactsPanel);
            app.dBLabel.Text = 'dB';
            app.sLabel_2 = uilabel(app.SignaloptionsandartefactsPanel);
            app.sLabel_2.Text = 's';
            app.MovementDropDownLabel = uilabel(app.SignaloptionsandartefactsPanel);
            app.MovementDropDownLabel.Text = 'Movement';
            app.MovementDropDown = uidropdown(app.SignaloptionsandartefactsPanel);
            app.MovementDropDown.Items = {'Right hand', 'Left hand', 'Feet'};
            app.MovementDropDown.Value = 'Right hand';
            app.FsEditFieldLabel = uilabel(app.SignaloptionsandartefactsPanel);
            app.FsEditFieldLabel.Text = 'Fs';
            app.FsEditField = uieditfield(app.SignaloptionsandartefactsPanel, 'numeric');
            app.FsEditField.Value = 256;
            app.DurationEditFieldLabel = uilabel(app.SignaloptionsandartefactsPanel);
            app.DurationEditFieldLabel.Text = 'Duration';
            app.DurationEditField = uieditfield(app.SignaloptionsandartefactsPanel, 'numeric');
            app.DurationEditField.Value = 20;
            app.SNREditFieldLabel = uilabel(app.SignaloptionsandartefactsPanel);
            app.SNREditFieldLabel.Text = 'SNR';
            app.SNREditField = uieditfield(app.SignaloptionsandartefactsPanel, 'numeric');
            app.SNREditField.Value = 10;
            app.EyeblinkCheckBox = uicheckbox(app.SignaloptionsandartefactsPanel);
            app.EyeblinkCheckBox.Text = 'Eye blink';
            app.EyeblinkCheckBox.Value = true;
            app.EMGeffectsCheckBox = uicheckbox(app.SignaloptionsandartefactsPanel);
            app.EMGeffectsCheckBox.Text = 'EMG effects';
            app.EMGeffectsCheckBox.Value = true;
            app.EOGeffectsCheckBox = uicheckbox(app.SignaloptionsandartefactsPanel);
            app.EOGeffectsCheckBox.Text = 'EOG effects';
            app.EOGeffectsCheckBox.Value = true;
            app.PowerlinesCheckBox = uicheckbox(app.SignaloptionsandartefactsPanel);
            app.PowerlinesCheckBox.Text = 'Power lines';
            app.PowerlinesCheckBox.Value = true;
            app.GeneratedataButton = uibutton(app.SignaloptionsandartefactsPanel, 'push');
            app.GeneratedataButton.ButtonPushedFcn = createCallbackFcn(app, @Generate, true);
            app.GeneratedataButton.Text = 'Generate data';
            app.FilePanel = uipanel(app.DataTab);
            app.FilePanel.Title = 'File';
            app.FilenameEditFieldLabel = uilabel(app.FilePanel);
            app.FilenameEditFieldLabel.Text = 'Filename';
            app.FilenameEditField = uieditfield(app.FilePanel, 'text');
            app.LoaddataButton = uibutton(app.FilePanel, 'push');
            app.LoaddataButton.ButtonPushedFcn = createCallbackFcn(app, @Load, true);
            app.LoaddataButton.Text = 'Load data';
            app.MovementStartingTimeLabel = uilabel(app.DataTab);
            app.MovementStartingTimeLabel.Text = {'Movement '; 'Starting '; 'Time'};
            app.MovementStartingTimeEditField = uieditfield(app.DataTab, 'numeric');
            app.MovementStartingTimeEditField.Value = 5;
            app.UIAxes = uiaxes(app.DataTab);
            title(app.UIAxes, 'Raw data')
            xlabel(app.UIAxes, 'time (s)')
            ylabel(app.UIAxes, 'Amplitude (uV)')
            app.PreprocessingTab = uitab(app.TabGroup);
            app.PreprocessingTab.Title = 'Pre-processing';
            app.BPFilterCheckBox = uicheckbox(app.PreprocessingTab);
            app.BPFilterCheckBox.Text = 'BP Filter';
            app.BPFilterCheckBox.Value = true;
            app.NotchFilterCheckBox = uicheckbox(app.PreprocessingTab);
            app.NotchFilterCheckBox.Text = 'Notch Filter';
            app.NotchFilterCheckBox.Value = true;
            app.BPFilterparametersPanel = uipanel(app.PreprocessingTab);
            app.BPFilterparametersPanel.Title = 'BP Filter parameters';
            app.TypeDropDownLabel = uilabel(app.BPFilterparametersPanel);
            app.TypeDropDownLabel.Text = 'Type';
            app.TypeDropDown = uidropdown(app.BPFilterparametersPanel);
            app.TypeDropDown.Items = {'fir', 'but', 'firws', 'brickwall'};
            app.TypeDropDown.Value = 'fir';
            app.OrderEditFieldLabel = uilabel(app.BPFilterparametersPanel);
            app.OrderEditFieldLabel.Text = 'Order';
            app.OrderEditField = uieditfield(app.BPFilterparametersPanel, 'numeric');
            app.OrderEditField.Value = 4;
            app.LFreqEditFieldLabel = uilabel(app.BPFilterparametersPanel);
            app.LFreqEditFieldLabel.Text = 'LFreq';
            app.LFreqEditField = uieditfield(app.BPFilterparametersPanel, 'numeric');
            app.LFreqEditField.Value = 8;
            app.HFreqEditFieldLabel = uilabel(app.BPFilterparametersPanel);
            app.HFreqEditFieldLabel.Text = 'HFreq';
            app.HFreqEditField = uieditfield(app.BPFilterparametersPanel, 'numeric');
            app.HFreqEditField.Value = 15;
            app.WindowTypeLabel = uilabel(app.BPFilterparametersPanel);
            app.WindowTypeLabel.Text = {'Window '; 'Type'};
            app.WindowTypeDropDown = uidropdown(app.BPFilterparametersPanel);
            app.WindowTypeDropDown.Items = {'', 'hann', 'hamming', 'blackman', 'kaiser'};
            app.WindowTypeDropDown.Value = 'hamming';
            app.HzLabel_2 = uilabel(app.BPFilterparametersPanel);
            app.HzLabel_2.Text = 'Hz';
            app.HzLabel_3 = uilabel(app.BPFilterparametersPanel);
            app.HzLabel_3.Text = 'Hz';
            app.ApplytemporalfilteringButton = uibutton(app.PreprocessingTab, 'push');
            app.ApplytemporalfilteringButton.ButtonPushedFcn = createCallbackFcn(app, @temporal_filtering, true);
            app.ApplytemporalfilteringButton.Text = 'Apply temporal filtering';
            app.TemporalFilteringLabel = uilabel(app.PreprocessingTab);
            app.TemporalFilteringLabel.Text = 'Temporal Filtering';
            app.SpatialFilteringLabel = uilabel(app.PreprocessingTab);
            app.SpatialFilteringLabel.Text = 'Spatial Filtering';
            app.FilteringMethodDropDownLabel = uilabel(app.PreprocessingTab);
            app.FilteringMethodDropDownLabel.Text = 'Filtering Method';
            app.FilteringMethodDropDown = uidropdown(app.PreprocessingTab);
            app.FilteringMethodDropDown.Items = {'CAR', 'ICA', 'CSP', 'Laplacian'};
            app.FilteringMethodDropDown.Value = 'CAR';
            app.SpatialFilterparametersPanel = uipanel(app.PreprocessingTab);
            app.SpatialFilterparametersPanel.Title = 'Spatial Filter parameters';
            app.ApplyspatialfilteringButton = uibutton(app.PreprocessingTab, 'push');
            app.ApplyspatialfilteringButton.ButtonPushedFcn = createCallbackFcn(app, @spatial_filtering, true);
            app.ApplyspatialfilteringButton.Text = 'Apply spatial filtering';
            app.NotchFilterparametersPanel = uipanel(app.PreprocessingTab);
            app.NotchFilterparametersPanel.Title = 'Notch Filter parameters';
            app.FreqEditFieldLabel = uilabel(app.NotchFilterparametersPanel);
            app.FreqEditFieldLabel.Text = 'Freq';
            app.FreqEditField = uieditfield(app.NotchFilterparametersPanel, 'numeric');
            app.FreqEditField.Value = 50;
            app.HzLabel_6 = uilabel(app.NotchFilterparametersPanel);
            app.HzLabel_6.Text = 'Hz';
            app.ExtractionTab = uitab(app.TabGroup);
            app.ExtractionTab.Title = 'Extraction';
            app.ExtractionMethodDropDownLabel = uilabel(app.ExtractionTab);
            app.ExtractionMethodDropDownLabel.Text = 'Extraction Method';
            app.ExtractionMethodDropDown = uidropdown(app.ExtractionTab);
            app.ExtractionMethodDropDown.Items = {'Power Bands', 'Wavelets', 'AR model'};
            app.ExtractionMethodDropDown.Value = 'Power Bands';
            app.ClassificiationTab = uitab(app.TabGroup);
            app.ClassificiationTab.Title = 'Classificiation';
            app.ClassificationMethodDropDownLabel = uilabel(app.ClassificiationTab);
            app.ClassificationMethodDropDownLabel.Text = 'Classification Method ';
            app.ClassificationMethodDropDown = uidropdown(app.ClassificiationTab);
            app.ClassificationMethodDropDown.Items = {'LDA', 'SVM', '...'};
            app.ClassificationMethodDropDown.Value = 'LDA';
            app.AnalysisTab = uitab(app.TabGroup);
            app.AnalysisTab.Title = 'Analysis';
            app.ResultsTab = uitab(app.TabGroup);
            app.ResultsTab.Title = 'Results';
        end
    end

    methods (Access = public)

        % Construct app
        function app = EEG_app

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end